classdef pneumonia_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResultPendingLabel         matlab.ui.control.Label
        PneumoniaDetectionAILabel  matlab.ui.control.Label
        LoadXrayimageButton        matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        trainedNet
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        data = load('resnet50_finetuned_model.mat');
        app.trainedNet = data.trainedNet_Finetuned;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: LoadXrayimageButton
        function LoadXrayimageButtonPushed(app, event)

    try
        % 1. Open a file dialog
        [file, path] = uigetfile({'*.jpeg';'*.jpg';'*.png'}, 'Select an X-ray Image');
        if isequal(file,0), return; end % Exit if user cancels
        
        % 2. Read and display the original image for a moment
        fullPath = fullfile(path, file);
        img = imread(fullPath);
        imshow(img, 'Parent', app.UIAxes);
        title(app.UIAxes, 'Processing...');
        drawnow; % Force MATLAB to update the display

        % 3. Preprocess the image for the network
        inputSize = app.trainedNet.Layers(1).InputSize;
        if size(img, 3) == 3, imgGray = rgb2gray(img); else, imgGray = img; end
        imgResized = imresize(imgGray, [inputSize(1) inputSize(2)]);
        imgForNet = cat(3, imgResized, imgResized, imgResized);
        
        % 4. Classify the image
        [label, confidence] = classify(app.trainedNet, imgForNet);
        
        % 5. Generate the Grad-CAM heatmap using the correct function name
        scoreMap = gradCAM(app.trainedNet, imgForNet, label);
        
        % 6. Display the heatmap overlayed on the image
        imshow(img, 'Parent', app.UIAxes); % Show the original image again
        hold(app.UIAxes, 'on');
        imagesc(app.UIAxes, 'CData', scoreMap, 'AlphaData', 0.4);
        colormap(app.UIAxes, 'jet');
        hold(app.UIAxes, 'off');
        
        % 7. Display the prediction text
        resultText = sprintf('Prediction: %s | Confidence: %.2f%%', label, max(confidence)*100);
        app.ResultPendingLabel.Text = resultText;
        
    catch ME
        disp('AN ERROR OCCURRED:');
        disp(ME.message);
        app.ResultPendingLabel.Text = 'Error during processing.';
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.LoadXrayimageButton = uibutton(app.UIFigure, 'push');
            app.LoadXrayimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadXrayimageButtonPushed, true);
            app.LoadXrayimageButton.Text = {'Load X-ray image'; ''; ''; ''};
            app.PneumoniaDetectionAILabel = uilabel(app.UIFigure);
            app.PneumoniaDetectionAILabel.Text = 'Pneumonia Detection AI';
            app.ResultPendingLabel = uilabel(app.UIFigure);
            app.ResultPendingLabel.Text = 'Result [Pending....]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pneumonia_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end